classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FasesdeTensinVSTiempoLabel     matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        GrficadeCorrienteTensionVSTiempoTab  matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        SealesVSTiempoLabel            matlab.ui.control.Label
        CorrientevsTiempoButton        matlab.ui.control.Button
        TensionvsTiempoButton          matlab.ui.control.Button
        GrficadeFaseTensinVSTiempoTab  matlab.ui.container.Tab
        UIAxes2                        matlab.ui.control.UIAxes
        SeleccioneUnaFasedeTensinvsTiempoLabel  matlab.ui.control.Label
        FaseA_TencionButton            matlab.ui.control.Button
        FaseB_TencionButton            matlab.ui.control.Button
        FaseC_TencionButton            matlab.ui.control.Button
        GrficadeFaseCorrienteVSTiempoTab  matlab.ui.container.Tab
        UIAxes3                        matlab.ui.control.UIAxes
        FasesdeCorrienteVSTiempoLabel  matlab.ui.control.Label
        SeleccioneUnaFasedeCorrientevsTiempoLabel  matlab.ui.control.Label
        FaseA_CorrienteButton          matlab.ui.control.Button
        FaseB_CorrienteButton          matlab.ui.control.Button
        FaseC_CorrienteButton          matlab.ui.control.Button
        GrficaRMSVSTiempoTab           matlab.ui.container.Tab
        RMSVSTiempoLabel               matlab.ui.control.Label
        UIAxes4                        matlab.ui.control.UIAxes
        TensionRMSvsTiempoButton       matlab.ui.control.Button
        CorrienteRMSvsTiempoButton     matlab.ui.control.Button
        FFTdelassealdeCorrienteTab     matlab.ui.container.Tab
        FFTCorrienteLabel              matlab.ui.control.Label
        UIAxes5                        matlab.ui.control.UIAxes
        FFTdelasealdecorrienteLabel    matlab.ui.control.Label
        FaseA_FFTCorrienteButton       matlab.ui.control.Button
        FaseB_FFTCorrienteButton       matlab.ui.control.Button
        FaseC_FFTCorrienteButton       matlab.ui.control.Button
        FFTdelasealdeTensinTab         matlab.ui.container.Tab
        FFTTensinLabel                 matlab.ui.control.Label
        UIAxes6                        matlab.ui.control.UIAxes
        FFTdelasealdetensinLabel       matlab.ui.control.Label
        FaseA_FFTTensionButton         matlab.ui.control.Button
        FaseB_FFTTensionButton         matlab.ui.control.Button
        FaseC_FFTTensionButton         matlab.ui.control.Button
        CorrienteDCVSTiempoTab         matlab.ui.container.Tab
        CorrienteEnDCVSTiempoLabel_2   matlab.ui.control.Label
        UIAxes7                        matlab.ui.control.UIAxes
        CorrienteDCVSTiempoButton_2    matlab.ui.control.Button
        PotenciaActivaVSTiempoTab      matlab.ui.container.Tab
        CorrienteEnDCVSTiempoLabel     matlab.ui.control.Label
        UIAxes8                        matlab.ui.control.UIAxes
        PotenciaActivaVSTiempoButton   matlab.ui.control.Button
        EvolucindelFactordePotenciaPFVSTiempoTab  matlab.ui.container.Tab
        UIAxes9                        matlab.ui.control.UIAxes
        EvolucinDelfactorDePotenciaPFVSTiempoButton  matlab.ui.control.Button
        EvolucindelFactordePotenciaPFVSTiempoLabel  matlab.ui.control.Label
        BuscarArchivoButton            matlab.ui.control.Button
        JonatanHuergoAguilarLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        data % Description   
    end

    methods (Access = private)

        % Button pushed function: CorrientevsTiempoButton
        function CorrientevsTiempoButtonPushed(app, event)
            % Hallamos y Graficamos  Corriente
            ylabel(app.UIAxes, 'Corriente');
            xlabel(app.UIAxes, 'Tiempo');
            corrx = app.data.Iabc.time;
            corry = app.data.Iabc.signals.values(:,:);
            plot(app.UIAxes,corrx,corry)  % Se grafican los resultados
        end

        % Button pushed function: TensionvsTiempoButton
        function TensionvsTiempoButtonPushed(app, event)
            % Hallamos y Graficamos Tension
            ylabel(app.UIAxes, 'Tensión');
            xlabel(app.UIAxes, 'Tiempo');
            tenx = app.data.Vabc.time;
            teny = app.data.Vabc.signals.values(:,:);
            plot(app.UIAxes,tenx,teny) % Se grafican los resultados
        end

        % Button pushed function: FaseA_TencionButton
        function FaseA_TencionButtonPushed(app, event)
            % Se halla y se grafica la Tension en A
            ylabel(app.UIAxes2, 'Tension en A');
            xlabel(app.UIAxes2, 'Tiempo');
            tenax = app.data.Vabc.time;
            tenay = app.data.Vabc.signals.values(:,1);
            plot(app.UIAxes2,tenax,tenay) % Se grafican los resultados
        end

        % Button pushed function: FaseB_TencionButton
        function FaseB_TencionButtonPushed(app, event)
            % Se halla y se grafica la Tension en B
            ylabel(app.UIAxes2, 'Tension en B');
            xlabel(app.UIAxes2, 'Tiempo');
            tenbx = app.data.Vabc.time;
            tenby = app.data.Vabc.signals.values(:,2);
            plot(app.UIAxes2,tenbx,tenby) % Se grafican los resultados
        end

        % Button pushed function: FaseC_TencionButton
        function FaseC_TencionButtonPushed(app, event)
            % Se halla y se grafica la Tension en C
            ylabel(app.UIAxes2, 'Tension en C');
            xlabel(app.UIAxes2, 'Tiempo');
            tencx = app.data.Vabc.time;
            tency = app.data.Vabc.signals.values(:,3);
            plot(app.UIAxes2,tencx,tency) % Se grafican los resultados
        end

        % Button pushed function: FaseA_CorrienteButton
        function FaseA_CorrienteButtonPushed(app, event)
            % Se halla y se grafica la Corriente en A
            ylabel(app.UIAxes3, 'Corriente en A');
            xlabel(app.UIAxes3, 'Tiempo');
            corrax = app.data.Iabc.time;
            corray = app.data.Iabc.signals.values(:,1);
            plot(app.UIAxes3,corrax,corray) % Se grafican los resultados    
        end

        % Button pushed function: FaseB_CorrienteButton
        function FaseB_CorrienteButtonPushed(app, event)
            % Se halla y se grafica la Corriente en B
            ylabel(app.UIAxes3, 'Corriente en B');
            xlabel(app.UIAxes3, 'Tiempo');
            corrbx = app.data.Iabc.time;
            corrby = app.data.Iabc.signals.values(:,2);
            plot(app.UIAxes3,corrbx,corrby) % Se grafican los resultados
        end

        % Button pushed function: FaseC_CorrienteButton
        function FaseC_CorrienteButtonPushed(app, event)
            % Se halla y se grafica la Corriente en C
            ylabel(app.UIAxes3, 'Corriente en C');
            xlabel(app.UIAxes3, 'Tiempo');
            corrcx = app.data.Iabc.time;
            corrcy = app.data.Iabc.signals.values(:,3);
            plot(app.UIAxes3,corrcx,corrcy) % Se grafican los resultados
        end

        % Button pushed function: TensionRMSvsTiempoButton
        function TensionRMSvsTiempoButtonPushed(app, event)
            % Se halla y se grafica TensionRMS en funcion del tiempo
            ylabel(app.UIAxes4, 'TensionRMS');
            xlabel(app.UIAxes4, 'Tiempo');
            tensix = app.data.Vabc.time; 
            %tensiy = rms(app.data.Vabc.signals.values(:,:)).*ones(1400001,1);
            %plot(app.UIAxes4,tensix,tensiy);
            tension = app.data.Vabc.signals.values(:,:);
            Vrms = rms(tension,2);
            plot(app.UIAxes4,tensix,Vrms); % Se grafican los resultados
            
        end

        % Button pushed function: CorrienteRMSvsTiempoButton
        function CorrienteRMSvsTiempoButtonPushed(app, event)
            % Se halla y se grafica CorrienteRMS en funcion del tiempo
            ylabel(app.UIAxes4, 'CorrienteRMS');
            xlabel(app.UIAxes4, 'Tiempo');
            corrienx = app.data.Iabc.time; 
            %cory = rms(app.data.Iabc.signals.values(:,:)).*ones(1400001,1);
            corriente = app.data.Iabc.signals.values(:,:);
            Irms = rms(corriente,2);
            plot(app.UIAxes4,corrienx, Irms); % Se grafican los resultados
        end

        % Button pushed function: FaseA_FFTCorrienteButton
        function FaseA_FFTCorrienteButtonPushed(app, event)
            % Se halla y se grafica Transformada de Fourier de la corriente en fase A en funcion del tiempo
            ylabel(app.UIAxes5, 'Transformada de Fourier Corriente en Fase A ');
            xlabel(app.UIAxes5, 'Tiempo');
            L2 = length(app.data.Iabc.signals.values(:,1));
            Fs = L2/app.data.Iabc.time(L2);
            T=1/Fs;

            ArrayLenght = 60*(1/60)/T;


            L = ArrayLenght;
            Y = fft(app.data.Iabc.signals.values((L2 - L:L2),1));
 
            P2 = abs(Y/L);
            P1=P2(1:L/2+1);

            P1(2:end-1)=2*P1(2:end-1);
            f=Fs*(0:(L/2))/L;
            
            
            plot(app.UIAxes5,f,P1,'r'); % Se grafican los resultados
            app.UIAxes5.XLim = [2e2 4e3]; % Se delimita  la grafica  en el eje X


        end

        % Button pushed function: FaseB_FFTCorrienteButton
        function FaseB_FFTCorrienteButtonPushed(app, event)
            % Se halla y se grafica Transformada de Fourier de la corriente en fase B en funcion del tiempo
            ylabel(app.UIAxes5, 'Transformada de Fourier Corriente en Fase B ');
            xlabel(app.UIAxes5, 'Tiempo');
            L2 = length(app.data.Iabc.signals.values(:,2));
            Fs = L2/app.data.Iabc.time(L2);
            T=1/Fs;

            ArrayLenght = 60*(1/60)/T;


            L = ArrayLenght;
            Y = fft(app.data.Iabc.signals.values((L2 - L:L2),1));
 
            P2 = abs(Y/L);
            P1=P2(1:L/2+1);

            P1(2:end-1)=2*P1(2:end-1);
            f=Fs*(0:(L/2))/L;
            
            
            plot(app.UIAxes5,f,P1,'b'); % Se grafican los resultados
            app.UIAxes5.XLim = [2e2 4e3]; % Se delimita  la grafica  en el eje X
        end

        % Button pushed function: FaseC_FFTCorrienteButton
        function FaseC_FFTCorrienteButtonPushed(app, event)
            % Se halla y se grafica Transformada de Fourier de la corriente en fase C en funcion del tiempo
            ylabel(app.UIAxes5, 'Transformada de Fourier Corriente en Fase C ');
            xlabel(app.UIAxes5, 'Tiempo');
            L2 = length(app.data.Iabc.signals.values(:,3));
            Fs = L2/app.data.Iabc.time(L2);
            T=1/Fs;

            ArrayLenght = 60*(1/60)/T;


            L = ArrayLenght;
            Y = fft(app.data.Iabc.signals.values((L2 - L:L2),1));
 
            P2 = abs(Y/L);
            P1=P2(1:L/2+1);

            P1(2:end-1)=2*P1(2:end-1);
            f=Fs*(0:(L/2))/L;
            
            
            plot(app.UIAxes5,f,P1,'g'); % Se grafican los resultados
            app.UIAxes5.XLim = [2e2 4e3]; % Se delimita  la grafica  en el eje X
        end

        % Button pushed function: FaseA_FFTTensionButton
        function FaseA_FFTTensionButtonPushed(app, event)
             % Se halla y se grafica Transformada de Fourier de la Tension en fase A en funcion del tiempo
             ylabel(app.UIAxes6, 'Transformada de Fourier Tension en Fase A ');
             xlabel(app.UIAxes6, 'Tiempo');
             L2 = length (app.data.Vabc.signals.values(:,1));
             Fs = L2/app.data.Vabc.time(L2);
             T=1/Fs;

             ArrayLenght = 60*(1/60)/T;


             L = ArrayLenght;
             Y = fft(app.data.Vabc.signals.values((L2 - L:L2),1));
 
             P2 = abs(Y/L);
             P1=P2(1:L/2+1);

             P1(2:end-1)=2*P1(2:end-1);
             f=Fs*(0:(L/2))/L;
            
            
            plot(app.UIAxes6,f,P1,'r'); % Se grafican los resultados
            app.UIAxes6.XLim = [2e2 4e3]; % Se delimita  la grafica  en el eje X
        end

        % Button pushed function: FaseB_FFTTensionButton
        function FaseB_FFTTensionButtonPushed(app, event)
             % Se halla y se grafica Transformada de Fourier de la Tension en fase B en funcion del tiempo
             ylabel(app.UIAxes6, 'Transformada de Fourier Tension en Fase B ');
             xlabel(app.UIAxes6, 'Tiempo');
             L2 = length(app.data.Vabc.signals.values(:,2));
             Fs = L2/app.data.Vabc.time(L2);
             T=1/Fs;

             ArrayLenght = 60*(1/60)/T;


             L = ArrayLenght;
             Y = fft(app.data.Vabc.signals.values((L2 - L:L2),1));
 
             P2 = abs(Y/L);
             P1=P2(1:L/2+1);

             P1(2:end-1)=2*P1(2:end-1);
             f=Fs*(0:(L/2))/L;
            
            
            plot(app.UIAxes6,f,P1,'b'); % Se grafican los resultados
            app.UIAxes6.XLim = [3e2 4e3]; % Se delimita  la grafica  en el eje X
        end

        % Button pushed function: FaseC_FFTTensionButton
        function FaseC_FFTTensionButtonPushed(app, event)
            % Se halla y se grafica Transformada de Fourier de la Tension en fase C en funcion del tiempo
             ylabel(app.UIAxes6, 'Transformada de Fourier Tension en Fase C ');
             xlabel(app.UIAxes6, 'Tiempo');
             L2 = length(app.data.Vabc.signals.values(:,3));
             Fs = L2/app.data.Vabc.time(L2);
             T=1/Fs;

             ArrayLenght = 60*(1/60)/T;


             L = ArrayLenght;
             Y = fft(app.data.Vabc.signals.values((L2 - L:L2),1));
 
             P2 = abs(Y/L);
             P1=P2(1:L/2+1);

             P1(2:end-1)=2*P1(2:end-1);
             f=Fs*(0:(L/2))/L;
            
            
             plot(app.UIAxes6,f,P1,'g'); % Se grafican los resultados
             app.UIAxes6.XLim = [3e2 4e3]; % Se delimita  la grafica  en el eje X
        end

        % Button pushed function: CorrienteDCVSTiempoButton_2
        function CorrienteDCVSTiempoButton_2Pushed(app, event)
            % Se halla y se grafica CorrienteDC en funcion del tiempo
            ylabel(app.UIAxes7, 'CorrienteDC');
            xlabel(app.UIAxes7, 'Tiempo');
            Ia = app.data.Iabc.signals.values(:,1);
            Ib = app.data.Iabc.signals.values(:,2);
            Ic = app.data.Iabc.signals.values(:,3);
            
            Va = app.data.Vabc.signals.values(:,1);
            Vb = app.data.Vabc.signals.values(:,2);
            Vc = app.data.Vabc.signals.values(:,3);
            
            vector1 = app.data.Vdc.signals.values(:,:);
            Idc = (Va .* Ia + Vb .* Ib + Vc .* Ic) ./ vector1;
            plot(app.UIAxes7, app.data.Vabc.time, Idc);% Se grafican los resultados
        end

        % Button pushed function: PotenciaActivaVSTiempoButton
        function PotenciaActivaVSTiempoButtonPushed(app, event)
            % Se halla y se grafica Potencia Activa en funcion del tiempo
            ylabel(app.UIAxes8, 'Potencia Activa ');
            xlabel(app.UIAxes8, 'Tiempo');
            Ia = app.data.Iabc.signals.values(:,1);
            Ib = app.data.Iabc.signals.values(:,2);
            Ic = app.data.Iabc.signals.values(:,3);
            
            Va = app.data.Vabc.signals.values(:,1);
            Vb = app.data.Vabc.signals.values(:,2);
            Vc = app.data.Vabc.signals.values(:,3);
            pac = Va .* Ia + Vb .* Ib + Vc .* Ic;
            plot(app.UIAxes8,app.data.Iabc.time, pac); % Se grafican los resultados
        end

        % Button pushed function: BuscarArchivoButton
        function BuscarArchivoButtonPushed(app, event)
            % Cargamos la base de datos a la app
            [file,path] = uigetfile('*.mat');
            if isequal(file,0)
            disp('User selected Cancel');
            else
            disp(['User selected ', fullfile(path, file)]);
            end
            
            app.data = load(file);
        end

        % Button pushed function: 
        % EvolucinDelfactorDePotenciaPFVSTiempoButton
        function EvolucinDelfactorDePotenciaPFVSTiempoButtonPushed(app, event)
            % Se halla y se grafica Evolución Del factor De Potencia (PF) en funcion del tiempo
            ylabel(app.UIAxes9,  'Evolución Del factor De Potencia (PF)');
            xlabel(app.UIAxes9, 'Tiempo');
            Ia = app.data.Iabc.signals.values(:,1);
            Ib = app.data.Iabc.signals.values(:,2);
            Ic = app.data.Iabc.signals.values(:,3);
            
            Va = app.data.Vabc.signals.values(:,1);
            Vb = app.data.Vabc.signals.values(:,2);
            Vc = app.data.Vabc.signals.values(:,3);
            
            Pf = cos(Va .* Ia + Vb .* Ib + Vc .* Ic);
            plot(app.UIAxes9,app.data.Iabc.time, Pf);% Se grafican los resultados
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FasesdeTensinVSTiempoLabel = uilabel(app.UIFigure);
            app.FasesdeTensinVSTiempoLabel.Text = 'Fases de Tensión VS Tiempo';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GrficadeCorrienteTensionVSTiempoTab = uitab(app.TabGroup);
            app.GrficadeCorrienteTensionVSTiempoTab.Title = 'Gráfica de Corriente/Tension VS Tiempo';
            app.UIAxes = uiaxes(app.GrficadeCorrienteTensionVSTiempoTab);
            title(app.UIAxes, 'Gráfica de Corriente/Tension VS Tiempo')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.SealesVSTiempoLabel = uilabel(app.GrficadeCorrienteTensionVSTiempoTab);
            app.SealesVSTiempoLabel.Text = 'Señales VS Tiempo';
            app.CorrientevsTiempoButton = uibutton(app.GrficadeCorrienteTensionVSTiempoTab, 'push');
            app.CorrientevsTiempoButton.ButtonPushedFcn = createCallbackFcn(app, @CorrientevsTiempoButtonPushed, true);
            app.CorrientevsTiempoButton.Text = {'Grafico de las 3 señales de'; ' Corriente vs Tiempo'};
            app.TensionvsTiempoButton = uibutton(app.GrficadeCorrienteTensionVSTiempoTab, 'push');
            app.TensionvsTiempoButton.ButtonPushedFcn = createCallbackFcn(app, @TensionvsTiempoButtonPushed, true);
            app.TensionvsTiempoButton.Text = {'Gráfico de las 3 señales de'; ' Tensiones vs Tiempo'};
            app.GrficadeFaseTensinVSTiempoTab = uitab(app.TabGroup);
            app.GrficadeFaseTensinVSTiempoTab.Title = 'Gráfica de Fase Tensión VS Tiempo';
            app.UIAxes2 = uiaxes(app.GrficadeFaseTensinVSTiempoTab);
            title(app.UIAxes2, 'Gráfica de Fase Tensión VS Tiempo')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.SeleccioneUnaFasedeTensinvsTiempoLabel = uilabel(app.GrficadeFaseTensinVSTiempoTab);
            app.SeleccioneUnaFasedeTensinvsTiempoLabel.Text = 'Seleccione Una Fase de Tensión vs Tiempo';
            app.FaseA_TencionButton = uibutton(app.GrficadeFaseTensinVSTiempoTab, 'push');
            app.FaseA_TencionButton.ButtonPushedFcn = createCallbackFcn(app, @FaseA_TencionButtonPushed, true);
            app.FaseA_TencionButton.Text = 'Fase A';
            app.FaseB_TencionButton = uibutton(app.GrficadeFaseTensinVSTiempoTab, 'push');
            app.FaseB_TencionButton.ButtonPushedFcn = createCallbackFcn(app, @FaseB_TencionButtonPushed, true);
            app.FaseB_TencionButton.Text = 'Fase B';
            app.FaseC_TencionButton = uibutton(app.GrficadeFaseTensinVSTiempoTab, 'push');
            app.FaseC_TencionButton.ButtonPushedFcn = createCallbackFcn(app, @FaseC_TencionButtonPushed, true);
            app.FaseC_TencionButton.Text = 'Fase C';
            app.GrficadeFaseCorrienteVSTiempoTab = uitab(app.TabGroup);
            app.GrficadeFaseCorrienteVSTiempoTab.Title = 'Gráfica de Fase Corriente VS Tiempo';
            app.UIAxes3 = uiaxes(app.GrficadeFaseCorrienteVSTiempoTab);
            title(app.UIAxes3, 'Gráfica de Fase Corriente VS Tiempo')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.FasesdeCorrienteVSTiempoLabel = uilabel(app.GrficadeFaseCorrienteVSTiempoTab);
            app.FasesdeCorrienteVSTiempoLabel.Text = 'Fases de Corriente VS Tiempo';
            app.SeleccioneUnaFasedeCorrientevsTiempoLabel = uilabel(app.GrficadeFaseCorrienteVSTiempoTab);
            app.SeleccioneUnaFasedeCorrientevsTiempoLabel.Text = 'Seleccione Una Fase de Corriente vs Tiempo';
            app.FaseA_CorrienteButton = uibutton(app.GrficadeFaseCorrienteVSTiempoTab, 'push');
            app.FaseA_CorrienteButton.ButtonPushedFcn = createCallbackFcn(app, @FaseA_CorrienteButtonPushed, true);
            app.FaseA_CorrienteButton.Text = 'Fase A';
            app.FaseB_CorrienteButton = uibutton(app.GrficadeFaseCorrienteVSTiempoTab, 'push');
            app.FaseB_CorrienteButton.ButtonPushedFcn = createCallbackFcn(app, @FaseB_CorrienteButtonPushed, true);
            app.FaseB_CorrienteButton.Text = 'Fase B';
            app.FaseC_CorrienteButton = uibutton(app.GrficadeFaseCorrienteVSTiempoTab, 'push');
            app.FaseC_CorrienteButton.ButtonPushedFcn = createCallbackFcn(app, @FaseC_CorrienteButtonPushed, true);
            app.FaseC_CorrienteButton.Text = 'Fase C';
            app.GrficaRMSVSTiempoTab = uitab(app.TabGroup);
            app.GrficaRMSVSTiempoTab.Title = 'Gráfica RMS VS Tiempo';
            app.RMSVSTiempoLabel = uilabel(app.GrficaRMSVSTiempoTab);
            app.RMSVSTiempoLabel.Text = 'RMS VS Tiempo';
            app.UIAxes4 = uiaxes(app.GrficaRMSVSTiempoTab);
            title(app.UIAxes4, 'Gráfica RMS VS Tiempo')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.TensionRMSvsTiempoButton = uibutton(app.GrficaRMSVSTiempoTab, 'push');
            app.TensionRMSvsTiempoButton.ButtonPushedFcn = createCallbackFcn(app, @TensionRMSvsTiempoButtonPushed, true);
            app.TensionRMSvsTiempoButton.Text = 'Tension RMS vs Tiempo';
            app.CorrienteRMSvsTiempoButton = uibutton(app.GrficaRMSVSTiempoTab, 'push');
            app.CorrienteRMSvsTiempoButton.ButtonPushedFcn = createCallbackFcn(app, @CorrienteRMSvsTiempoButtonPushed, true);
            app.CorrienteRMSvsTiempoButton.Text = 'Corriente RMS vs Tiempo';
            app.FFTdelassealdeCorrienteTab = uitab(app.TabGroup);
            app.FFTdelassealdeCorrienteTab.Title = 'FFT de las señal de Corriente';
            app.FFTCorrienteLabel = uilabel(app.FFTdelassealdeCorrienteTab);
            app.FFTCorrienteLabel.Text = 'FFT - Corriente';
            app.UIAxes5 = uiaxes(app.FFTdelassealdeCorrienteTab);
            title(app.UIAxes5, 'FFT de las señal de Corriente')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            app.FFTdelasealdecorrienteLabel = uilabel(app.FFTdelassealdeCorrienteTab);
            app.FFTdelasealdecorrienteLabel.Text = 'FFT de la señal de corriente';
            app.FaseA_FFTCorrienteButton = uibutton(app.FFTdelassealdeCorrienteTab, 'push');
            app.FaseA_FFTCorrienteButton.ButtonPushedFcn = createCallbackFcn(app, @FaseA_FFTCorrienteButtonPushed, true);
            app.FaseA_FFTCorrienteButton.Text = 'Fase A';
            app.FaseB_FFTCorrienteButton = uibutton(app.FFTdelassealdeCorrienteTab, 'push');
            app.FaseB_FFTCorrienteButton.ButtonPushedFcn = createCallbackFcn(app, @FaseB_FFTCorrienteButtonPushed, true);
            app.FaseB_FFTCorrienteButton.Text = 'Fase B';
            app.FaseC_FFTCorrienteButton = uibutton(app.FFTdelassealdeCorrienteTab, 'push');
            app.FaseC_FFTCorrienteButton.ButtonPushedFcn = createCallbackFcn(app, @FaseC_FFTCorrienteButtonPushed, true);
            app.FaseC_FFTCorrienteButton.Text = 'Fase C';
            app.FFTdelasealdeTensinTab = uitab(app.TabGroup);
            app.FFTdelasealdeTensinTab.Title = 'FFT de la señal de Tensión';
            app.FFTTensinLabel = uilabel(app.FFTdelasealdeTensinTab);
            app.FFTTensinLabel.Text = 'FFT - Tensión';
            app.UIAxes6 = uiaxes(app.FFTdelasealdeTensinTab);
            title(app.UIAxes6, 'FFT de la señal de Tensión')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            app.FFTdelasealdetensinLabel = uilabel(app.FFTdelasealdeTensinTab);
            app.FFTdelasealdetensinLabel.Text = 'FFT de la señal de tensión';
            app.FaseA_FFTTensionButton = uibutton(app.FFTdelasealdeTensinTab, 'push');
            app.FaseA_FFTTensionButton.ButtonPushedFcn = createCallbackFcn(app, @FaseA_FFTTensionButtonPushed, true);
            app.FaseA_FFTTensionButton.Text = 'Fase A';
            app.FaseB_FFTTensionButton = uibutton(app.FFTdelasealdeTensinTab, 'push');
            app.FaseB_FFTTensionButton.ButtonPushedFcn = createCallbackFcn(app, @FaseB_FFTTensionButtonPushed, true);
            app.FaseB_FFTTensionButton.Text = 'Fase B';
            app.FaseC_FFTTensionButton = uibutton(app.FFTdelasealdeTensinTab, 'push');
            app.FaseC_FFTTensionButton.ButtonPushedFcn = createCallbackFcn(app, @FaseC_FFTTensionButtonPushed, true);
            app.FaseC_FFTTensionButton.Text = 'Fase C';
            app.CorrienteDCVSTiempoTab = uitab(app.TabGroup);
            app.CorrienteDCVSTiempoTab.Title = 'Corriente DC VS Tiempo';
            app.CorrienteEnDCVSTiempoLabel_2 = uilabel(app.CorrienteDCVSTiempoTab);
            app.CorrienteEnDCVSTiempoLabel_2.Text = 'Corriente En DC VS Tiempo';
            app.UIAxes7 = uiaxes(app.CorrienteDCVSTiempoTab);
            title(app.UIAxes7, 'Corriente DC VS Tiempo')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            app.CorrienteDCVSTiempoButton_2 = uibutton(app.CorrienteDCVSTiempoTab, 'push');
            app.CorrienteDCVSTiempoButton_2.ButtonPushedFcn = createCallbackFcn(app, @CorrienteDCVSTiempoButton_2Pushed, true);
            app.CorrienteDCVSTiempoButton_2.Text = 'Corriente DC VS Tiempo';
            app.PotenciaActivaVSTiempoTab = uitab(app.TabGroup);
            app.PotenciaActivaVSTiempoTab.Title = 'Potencia Activa VS Tiempo';
            app.CorrienteEnDCVSTiempoLabel = uilabel(app.PotenciaActivaVSTiempoTab);
            app.CorrienteEnDCVSTiempoLabel.Text = 'Corriente En DC VS Tiempo';
            app.UIAxes8 = uiaxes(app.PotenciaActivaVSTiempoTab);
            title(app.UIAxes8, 'Potencia Activa VS Tiempo')
            xlabel(app.UIAxes8, 'X')
            ylabel(app.UIAxes8, 'Y')
            app.PotenciaActivaVSTiempoButton = uibutton(app.PotenciaActivaVSTiempoTab, 'push');
            app.PotenciaActivaVSTiempoButton.ButtonPushedFcn = createCallbackFcn(app, @PotenciaActivaVSTiempoButtonPushed, true);
            app.PotenciaActivaVSTiempoButton.Text = 'Potencia Activa VS Tiempo';
            app.EvolucindelFactordePotenciaPFVSTiempoTab = uitab(app.TabGroup);
            app.EvolucindelFactordePotenciaPFVSTiempoTab.Title = 'Evolución del Factor de Potencia (PF) VS Tiempo';
            app.UIAxes9 = uiaxes(app.EvolucindelFactordePotenciaPFVSTiempoTab);
            title(app.UIAxes9, 'Evolución del Factor de Potencia (PF) VS Tiempo')
            xlabel(app.UIAxes9, 'X')
            ylabel(app.UIAxes9, 'Y')
            app.EvolucinDelfactorDePotenciaPFVSTiempoButton = uibutton(app.EvolucindelFactordePotenciaPFVSTiempoTab, 'push');
            app.EvolucinDelfactorDePotenciaPFVSTiempoButton.ButtonPushedFcn = createCallbackFcn(app, @EvolucinDelfactorDePotenciaPFVSTiempoButtonPushed, true);
            app.EvolucinDelfactorDePotenciaPFVSTiempoButton.Text = {'Evolución Del factor De Potencia (PF) '; 'VS Tiempo'};
            app.EvolucindelFactordePotenciaPFVSTiempoLabel = uilabel(app.EvolucindelFactordePotenciaPFVSTiempoTab);
            app.EvolucindelFactordePotenciaPFVSTiempoLabel.Text = 'Evolución del Factor de Potencia (PF) VS Tiempo';
            app.BuscarArchivoButton = uibutton(app.UIFigure, 'push');
            app.BuscarArchivoButton.ButtonPushedFcn = createCallbackFcn(app, @BuscarArchivoButtonPushed, true);
            app.BuscarArchivoButton.Text = 'Buscar Archivo';
            app.JonatanHuergoAguilarLabel = uilabel(app.UIFigure);
            app.JonatanHuergoAguilarLabel.Text = 'Jonatan Huergo Aguilar';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end